classdef Alkahest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AlkahestUIFigure           matlab.ui.Figure
        SettingsPanel              matlab.ui.container.Panel
        SetergoingButton           matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        ChoosefileButton           matlab.ui.control.Button
        RixellengthSlider          matlab.ui.control.Slider
        RixellengthSliderLabel     matlab.ui.control.Label
        SimulationtimeSlider       matlab.ui.control.Slider
        SimulationtimeSliderLabel  matlab.ui.control.Label
        NumberofrixelsSlider       matlab.ui.control.Slider
        NumberofrixelsSliderLabel  matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Root % Root directory path
        Filename % Name of original file within this directory
        fieldSettings % Settings for entire field
        cellSettingsType
        cellSettings % Settings for individual rods
        dispSettings % Settings for plotting outputs
        areaFrac % Density of system
        targetSimTime % Total simulation time
        samplingRate % Frequency (in simulation time) with which system should be sampled for plotting
        compiled % Whether .mex file has been compiled on this system
        gpuAvailable % Whether a Matlab-compatible GPU is available on this system
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % ADD FUNCTIONS TO PATH
            locPath = mfilename('fullpath');
            locPath = locPath(1:end-8); %Path without the uneccessary 'Alkahest' on the end
            addpath(genpath(locPath))
                                    
            % PARAMETERS
            % Settings applied to entire field
            app.fieldSettings.burnIndt = 0.001;
            app.fieldSettings.burnInSteps = 100;
            app.fieldSettings.motiledt = 0.04;
          
            % Choose your cell and barrier settings
            app.cellSettingsType = 'Mapped'; %Mapped is the only choice now
            app.cellSettings.noCells = app.NumberofrixelsSlider.Value;
            app.cellSettings.a = app.RixellengthSlider.Value;
            app.cellSettings.r = 0;
            app.cellSettings.f = 1;
            
            app.dispSettings.ImgPath = 'Frame_%04d.tif';
            app.dispSettings.colourCells = 'None';
            
            app.areaFrac = 0.25;
            app.targetSimTime = app.SimulationtimeSlider.Value;
            app.samplingRate = 1.0;
            app.Root = matlabroot;
            
            % AXES SETUP
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];            
        end

        % Button pushed function: ChoosefileButton
        function ChoosefileButtonPushed(app, event)
            %Ensure chosen folder contains readable data.          
            [app.Filename,app.Root] = uigetfile({'*.*','All Files (*.*)'});
            if app.Filename ~= 0
                cd(app.Root)

                if exist(fullfile(app.Root,app.Filename),'file')
                    try
                        fullPath = fullfile(app.Root,app.Filename);
                        app.cellSettings.colourField = imread(fullPath);
                        bwImg = mean(app.cellSettings.colourField,3);
                        maxDim = max(size(app.cellSettings.colourField));
                        app.cellSettings.oriField = findImageOrients(bwImg,maxDim/40);
                        app.Label.Text = fullPath;
                        app.dispSettings.imagedirectory = [app.Root,filesep,'MeltingTimecourse'];

                        % Fill and format the relevant axes
                        imshow(app.cellSettings.colourField,'Parent',app.UIAxes)
                        imagesc(app.UIAxes_2,app.cellSettings.oriField)
                        axis(app.UIAxes_2,'equal')
                        axis(app.UIAxes_2,'tight')
                        colormap(app.UIAxes_2,'hsv')

                        % Allow user to begin simulation to begin
                        app.SetergoingButton.Enable = 'on';
                    catch
                        errordlg('Does not appear to be a Matlab readable RGB image! Please try again.')
                        app.Filename = [];
                    end
                end
            else
                app.Root = matlabroot;
                app.Filename = [];
            end
        end

        % Value changed function: NumberofrixelsSlider
        function NumberofrixelsSliderValueChanged(app, event)
            value = app.NumberofrixelsSlider.Value;
            if value == 0
                value = 1;
            end
            app.cellSettings.noCells = ceil(value);
        end

        % Value changed function: SimulationtimeSlider
        function SimulationtimeSliderValueChanged(app, event)
            value = app.SimulationtimeSlider.Value;
            app.targetSimTime = round(value);
        end

        % Value changed function: RixellengthSlider
        function RixellengthSliderValueChanged(app, event)
            value = app.RixellengthSlider.Value;
            app.cellSettings.a = value;
        end

        % Button pushed function: SetergoingButton
        function SetergoingButtonPushed(app, event)
            %% Initialise field %%
            
            %Set up the system dimensions to give the desired system density
            app.fieldSettings.xWidth = 1;
            app.fieldSettings.yHeight = 1;
            trialField = WensinkField(app.fieldSettings);
            trialField = trialField.populateField(app.cellSettingsType,app.cellSettings,Inf);
            thisArea = trialField.getAreaFraction();
            
            fieldAspRat = size(app.cellSettings.oriField,1)/size(app.cellSettings.oriField,2);
            totArea = thisArea/app.areaFrac;
            
            app.fieldSettings.xWidth = sqrt(totArea/fieldAspRat);
            app.fieldSettings.yHeight = sqrt(totArea*fieldAspRat);
                        
            app.cellSettings.fieldScaling = app.fieldSettings.xWidth/size(app.cellSettings.oriField,2);
            
            %Now that all parameters are set, initialise the real field
            startField = WensinkField(app.fieldSettings);
            startField = startField.populateField(app.cellSettingsType,app.cellSettings,NaN);
            
            %And set up the output image directory
            
            if ~exist(app.dispSettings.imagedirectory,'dir')
                mkdir(app.dispSettings.imagedirectory)
            end
            
            %% Run simulation %%
            
            %Do initial burn-in simulation to allow system to relax a little (prevents
            %numerical instabilities later)
            app.fieldSettings.FrameSkip = Inf;
            intermediateField = simulateWensinkFieldBurnIn(startField,app.fieldSettings);
            
            %Do a second burn-in, with a slightly larger value of dt
            app.fieldSettings.burnIndt = 0.005;
            intermediateField = simulateWensinkFieldBurnIn(intermediateField,app.fieldSettings);
            
            %Do a third burn-in, with a slightly larger value of dt
%             app.fieldSettings.burnIndt = 0.02;
%             intermediateField = simulateWensinkFieldBurnIn(intermediateField,app.fieldSettings);

            %Set up a motility gradient, so cells on the right move at
            %maximal speed while those on the left remain frozen
            intermediateField.fCells = intermediateField.xCells/max(intermediateField.xCells);
            
            %Finally, do the full simulation.
            app.fieldSettings.FrameSkip = round(app.samplingRate/app.fieldSettings.motiledt);
            app.fieldSettings.motileSteps = ceil(app.targetSimTime/(app.fieldSettings.motiledt*app.fieldSettings.FrameSkip))*app.fieldSettings.FrameSkip;
            simulateWensinkField(intermediateField,app.fieldSettings,app.dispSettings,app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AlkahestUIFigure = uifigure('Visible', 'off');
            app.AlkahestUIFigure.Name = 'Alkahest';
            app.UIAxes = uiaxes(app.AlkahestUIFigure);
            title(app.UIAxes, 'Original image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.AlkahestUIFigure);
            title(app.UIAxes_2, 'Orientation map')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes2 = uiaxes(app.AlkahestUIFigure);
            title(app.UIAxes2, 'Current image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.SettingsPanel = uipanel(app.AlkahestUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.NumberofrixelsSliderLabel = uilabel(app.SettingsPanel);
            app.NumberofrixelsSliderLabel.Text = 'Number of rixels ';
            app.NumberofrixelsSlider = uislider(app.SettingsPanel);
            app.NumberofrixelsSlider.Limits = [0 8000];
            app.NumberofrixelsSlider.MajorTicks = [0 2000 4000 6000 8000];
            app.NumberofrixelsSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofrixelsSliderValueChanged, true);
            app.NumberofrixelsSlider.Value = 1000;
            app.SimulationtimeSliderLabel = uilabel(app.SettingsPanel);
            app.SimulationtimeSliderLabel.Text = 'Simulation time';
            app.SimulationtimeSlider = uislider(app.SettingsPanel);
            app.SimulationtimeSlider.Limits = [0 1000];
            app.SimulationtimeSlider.ValueChangedFcn = createCallbackFcn(app, @SimulationtimeSliderValueChanged, true);
            app.SimulationtimeSlider.Value = 250;
            app.RixellengthSliderLabel = uilabel(app.SettingsPanel);
            app.RixellengthSliderLabel.Text = 'Rixel length';
            app.RixellengthSlider = uislider(app.SettingsPanel);
            app.RixellengthSlider.Limits = [2 10];
            app.RixellengthSlider.ValueChangedFcn = createCallbackFcn(app, @RixellengthSliderValueChanged, true);
            app.RixellengthSlider.Value = 8;
            app.ChoosefileButton = uibutton(app.SettingsPanel, 'push');
            app.ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefileButtonPushed, true);
            app.ChoosefileButton.Text = 'Choose file';
            app.Label = uilabel(app.SettingsPanel);
            app.Label.Text = '';
            app.SetergoingButton = uibutton(app.SettingsPanel, 'push');
            app.SetergoingButton.ButtonPushedFcn = createCallbackFcn(app, @SetergoingButtonPushed, true);
            app.SetergoingButton.Enable = 'off';
            app.SetergoingButton.Text = 'Set ''er going!';
            % Show the figure after all components are created
            app.AlkahestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Alkahest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AlkahestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AlkahestUIFigure)
        end
    end
end